classdef gui_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                 matlab.ui.Figure
        TabGroup_main           matlab.ui.container.TabGroup
        Tab_encrypted           matlab.ui.container.Tab
        btn_a_encrypt           matlab.ui.control.Button
        Lamp_en_status          matlab.ui.control.Lamp
        Label_en_status         matlab.ui.control.Label
        Panel_en                matlab.ui.container.Panel
        btn_a_input_img         matlab.ui.control.Button
        btn_a_input_robust      matlab.ui.control.Button
        btn_a_input_frag        matlab.ui.control.Button
        axes_a_original_img     matlab.ui.control.UIAxes
        axes_a_en_robust        matlab.ui.control.UIAxes
        axes_a_en_frag          matlab.ui.control.UIAxes
        Panel_show_a            matlab.ui.container.Panel
        axes_a_en_robust_show   matlab.ui.control.UIAxes
        axes_a_en_frag_show     matlab.ui.control.UIAxes
        Label                   matlab.ui.control.Label
        DropDown_en_robust      matlab.ui.control.DropDown
        Label_2                 matlab.ui.control.Label
        DropDown_en_frag        matlab.ui.control.DropDown
        loading_en_Image        matlab.ui.control.Image
        Tab_decrypt             matlab.ui.container.Tab
        Panel_de                matlab.ui.container.Panel
        btn_a_change_img        matlab.ui.control.Button
        axes_a_de_img           matlab.ui.control.UIAxes
        btn_a_zero_robust       matlab.ui.control.Button
        btn_a_zero_frag         matlab.ui.control.Button
        btn_a_key               matlab.ui.control.Button
        key_path_Label          matlab.ui.control.Label
        text_Label              matlab.ui.control.Label
        axes_a_de_robust        matlab.ui.control.UIAxes
        axes_a_de_frag          matlab.ui.control.UIAxes
        btn_a_decrypt           matlab.ui.control.Button
        Panel_show_c            matlab.ui.container.Panel
        axes_a_de_robust_show   matlab.ui.control.UIAxes
        axes_a_de_frag_show     matlab.ui.control.UIAxes
        Lamp_de_status          matlab.ui.control.Lamp
        Label_de_status         matlab.ui.control.Label
        Label_3                 matlab.ui.control.Label
        DropDown_de_robust      matlab.ui.control.DropDown
        Label_4                 matlab.ui.control.Label
        DropDown_de_frag        matlab.ui.control.DropDown
        loading_de_Image        matlab.ui.control.Image
        Tab_editing             matlab.ui.container.Tab
        btn_b_confirm_change    matlab.ui.control.Button
        axes_b_original_img     matlab.ui.control.UIAxes
        axes_b_preview_img      matlab.ui.control.UIAxes
        TabGroup_b_subgroup     matlab.ui.container.TabGroup
        Tab_rotate              matlab.ui.container.Tab
        Knob_rotate_angle       matlab.ui.control.Knob
        EditField_rotate_angle  matlab.ui.control.NumericEditField
        Tab_gaussian            matlab.ui.container.Tab
        Slider_gaussian         matlab.ui.control.Slider
        EditField_gaussian      matlab.ui.control.NumericEditField
        Tab_salt                matlab.ui.container.Tab
        Slider_salt             matlab.ui.control.Slider
        EditField_salt          matlab.ui.control.NumericEditField
        Tab_crop                matlab.ui.container.Tab
        btn_crop                matlab.ui.control.Button
        Tab_jpeg                matlab.ui.container.Tab
        Knob_jpeg               matlab.ui.control.Knob
        EditField_jpeg          matlab.ui.control.NumericEditField
        Tab_medfilt             matlab.ui.container.Tab
        Slider_midfilt          matlab.ui.control.Slider
        EditField_midfilt       matlab.ui.control.NumericEditField
        axes_b_changed_img      matlab.ui.control.UIAxes
        btn_b_confirm_tmp       matlab.ui.control.Button
        btn_b_redo              matlab.ui.control.Button
        Tab_similarity          matlab.ui.container.Tab
        axes_s_robust_original  matlab.ui.control.UIAxes
        axes_s_frag_original    matlab.ui.control.UIAxes
        axes_s_robust_decrypt   matlab.ui.control.UIAxes
        axes_s_frag_decrypt     matlab.ui.control.UIAxes
        btn_s_robust_original   matlab.ui.control.Button
        btn_s_frag_original     matlab.ui.control.Button
        btn_s_robust_decrypt    matlab.ui.control.Button
        btn_s_frag_decrypt      matlab.ui.control.Button
        NCLabel                 matlab.ui.control.Label
        EditField_nc_robust     matlab.ui.control.NumericEditField
        TAFLabel                matlab.ui.control.Label
        EditField_taf_robust    matlab.ui.control.NumericEditField
        NCLabel_2               matlab.ui.control.Label
        EditField_nc_frag       matlab.ui.control.NumericEditField
        TAFLabel_2              matlab.ui.control.Label
        EditField_taf_frag      matlab.ui.control.NumericEditField
        PoweredbyLabel          matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global img_flag;
            global robust_flag;
            global frag_flag;
            img_flag = 0;
            robust_flag = 0;
            frag_flag = 0;
            
            global zero_robust_flag;
            global test_img_flag;
            global zero_frag_flag;
            global key_flag;
            zero_robust_flag = 0;
            test_img_flag = 0;
            zero_frag_flag = 0;
            key_flag = 0;
            
            global frag_original_flag;
            global frag_decrypt_flag;
            global robust_original_flag;
            global robust_decrypt_flag;
            frag_original_flag = 0;
            frag_decrypt_flag = 0;
            robust_original_flag = 0;
            robust_decrypt_flag = 0;
            
        end

        % Button pushed function: btn_a_decrypt
        function btn_a_decrypt_Callback(app, event)
            app.Lamp_de_status.Color = [1.00, 0.50, 0.00];
            app.Label_de_status.Text = '解密中';
            app.loading_de_Image.Visible = 'on';
            
            global om;
            global key;
            global zero_robust;
            global zero_frag;
            
            sign = 0;
            %             解密
            %             $ TODO:
            % 接口
            switch app.DropDown_de_robust.Value
                case 'BP网络'
                    if key.encrypt_function == 1
                        robust_seal = BP_robust_decrypt(om, key.net, zero_robust);
                    else
                        f = app.figure1;
                        uialert(f, '解密算法与密钥不一致', '错误');
                        app.Lamp_de_status.Color = [1.00, 0.00, 0.00];
                        app.Label_de_status.Text = '未完成';
                        app.loading_de_Image.Visible = 'off';
                        return;
                    end
                case 'CNN'
                    if key.encrypt_function == 2
                        robust_seal = CNN_robust_decrypt(om, key.net, zero_robust);
                    else
                        f = app.figure1;
                        uialert(f, '解密算法与密钥不一致', '错误');
                        app.Lamp_de_status.Color = [1.00, 0.00, 0.00];
                        app.Label_de_status.Text = '未完成';
                        app.loading_de_Image.Visible = 'off';
                        return;
                    end
            end
            
            switch app.DropDown_de_frag.Value
                case 'SVD'
                    if key.frag_function == 1
                        fragile_seal = SVD_frag_decrypt(om, zero_frag);
                    else
                        f = app.figure1;
                        uialert(f, '解密算法与密钥不一致', '错误');
                        app.Lamp_de_status.Color = [1.00, 0.00, 0.00];
                        app.Label_de_status.Text = '未完成';
                        app.loading_de_Image.Visible = 'off';
                        return;
                    end
                case '比特平面'
                    if key.frag_function == 2
                        fragile_seal = Bit_frag_decrypt(om, zero_frag);
                    else
                        f = app.figure1;
                        uialert(f, '解密算法与密钥不一致', '错误');
                        app.Lamp_de_status.Color = [1.00, 0.00, 0.00];
                        app.Label_de_status.Text = '未完成';
                        app.loading_de_Image.Visible = 'off';
                        return;
                    end
                    
            end


            I1 = imshow(robust_seal, 'InitialMagnification', 'fit', 'Parent', app.axes_a_de_robust_show, ...
                'XData', [1 app.axes_a_de_robust_show.Position(3)], 'YData', [1 app.axes_a_de_robust_show.Position(4)]);
            app.axes_a_de_robust_show.XLim = [0 I1.XData(2)];
            app.axes_a_de_robust_show.YLim = [0 I1.YData(2)];
            app.axes_a_de_robust_show.Title.String = '解密鲁棒水印';
            
            I2 = imshow(fragile_seal, 'InitialMagnification', 'fit', 'Parent', app.axes_a_de_frag_show, ...
                'XData', [1 app.axes_a_de_frag_show.Position(3)], 'YData', [1 app.axes_a_de_frag_show.Position(4)]);
            app.axes_a_de_frag_show.XLim = [0 I2.XData(2)];
            app.axes_a_de_frag_show.YLim = [0 I2.YData(2)];
            app.axes_a_de_frag_show.Title.String = '解密脆弱水印';
            
            imwrite(om, 'decryption/image.tif');
            imwrite(robust_seal, 'decryption/robust_seal.tif');
            imwrite(fragile_seal, 'decryption/fragile_seal.tif');
            
            app.Lamp_de_status.Color = [0.00, 1.00, 0.00];
            app.Label_de_status.Text = '完成';
            app.loading_de_Image.Visible = 'off';
        end

        % Button pushed function: btn_a_input_img
        function btn_a_input_img_Callback(app, event)
            global img_flag;
            global robust_flag;
            global frag_flag;
            global yt;
            
            [filename,pathname] = uigetfile({'*.*';'*.bmp';'*.jpg';'*.tif';'*.jpeg'},'选择图像');
            
            %如果没有输入路径，则弹出错误对话框
            if isequal(filename,0)||isequal(pathname,0)
                f = app.figure1;
                uialert(f, '您还没有选取图片', '注意', 'Icon', 'warning');
                return;
            else
                %合成路径+文件名
                image=[pathname,filename];
                %读取图像
                yt = imread(image);
                
                I = imshow(yt, 'InitialMagnification', 'fit', 'Parent', app.axes_a_original_img, ...
                    'XData', [1 app.axes_a_original_img.Position(3)], 'YData', [1 app.axes_a_original_img.Position(4)]);
                app.axes_a_original_img.XLim = [0 I.XData(2)];
                app.axes_a_original_img.YLim = [0 I.YData(2)];
                app.axes_a_original_img.Title.String = '原图';
                
                img_flag = 1;
            end
            
            if img_flag == 1 && robust_flag == 1 && frag_flag == 1
                app.btn_a_encrypt.Enable = 'on';
            end
        end

        % Button pushed function: btn_a_input_frag
        function btn_a_input_frag_Callback(app, event)
            global img_flag;
            global robust_flag;
            global frag_flag;
            
            %选择图片，可以是以下格式（*这个符号是通配符，表示可以是任意长度的任意字符）
            [filename,pathname] = uigetfile({'*.*';'*.bmp';'*.jpg';'*.tif';'*.jpeg'},'选择图像');
            %将im定义成全局变量，这样在代码的任何位置都可以使用im了。
            global cr;
            %如果没有输入路径，则弹出错误对话框
            if isequal(filename,0)||isequal(pathname,0)
                f = app.figure1;
                uialert(f, '您还没有选取图片', '注意', 'Icon', 'warning');
                return;
            else
                %合成路径+文件名
                image=[pathname,filename];
                %读取图像
                cr=imread(image);
                if size(cr,3) == 3
                    cr = rgb2gray(cr);
                end
                cr = logical(cr);
                
                I = imshow(cr, 'InitialMagnification', 'fit', 'Parent', app.axes_a_en_frag, ...
                    'XData', [1 app.axes_a_en_frag.Position(3)], 'YData', [1 app.axes_a_en_frag.Position(4)]);
                app.axes_a_en_frag.XLim = [0 I.XData(2)];
                app.axes_a_en_frag.YLim = [0 I.YData(2)];
                app.axes_a_en_frag.Title.String = '脆弱水印';
                
                frag_flag = 1;
            end
            
            if img_flag == 1 && robust_flag == 1 && frag_flag == 1
                app.btn_a_encrypt.Enable = 'on';
            end
        end

        % Button pushed function: btn_a_input_robust
        function btn_a_input_robust_Callback(app, event)
            global img_flag;
            global robust_flag;
            global frag_flag;
            global rb;
            
            %选择图片，可以是以下格式（*这个符号是通配符，表示可以是任意长度的任意字符）
            [filename,pathname] = uigetfile({'*.*';'*.bmp';'*.jpg';'*.tif';'*.jpeg'},'选择图像');
            
            %如果没有输入路径，则弹出错误对话框
            if isequal(filename,0)||isequal(pathname,0)
                f = app.figure1;
                uialert(f, '您还没有选取图片', '注意', 'Icon', 'warning');
                return;
            else
                %合成路径+文件名
                image=[pathname,filename];
                %读取图像
                rb=imread(image);
                if size(rb,3) == 3
                    rb = rgb2gray(rb);
                end
                rb = logical(rb);
                
                I = imshow(rb, 'InitialMagnification', 'fit', 'Parent', app.axes_a_en_robust, ...
                    'XData', [1 app.axes_a_en_robust.Position(3)], 'YData', [1 app.axes_a_en_robust.Position(4)]);
                app.axes_a_en_robust.XLim = [0 I.XData(2)];
                app.axes_a_en_robust.YLim = [0 I.YData(2)];
                app.axes_a_en_robust.Title.String = '鲁棒水印';
                
                robust_flag = 1;
            end
            
            if img_flag == 1 && robust_flag == 1 && frag_flag == 1
                app.btn_a_encrypt.Enable = 'on';
            end
        end

        % Button pushed function: btn_b_confirm_change
        function btn_b_confirm_change_Callback(app, event)
            global zero_robust_flag;
            global test_img_flag;
            global zero_frag_flag;
            global key_flag;
            
            global om;
            
            app.TabGroup_main.SelectedTab = app.TabGroup_main.Children(2);
            
            test_img_flag = 1;
            
            % 待解密图像
            I = imshow(om, 'InitialMagnification', 'fit', 'Parent', app.axes_a_de_img, ...
                'XData', [1 app.axes_a_de_img.Position(3)], 'YData', [1 app.axes_a_de_img.Position(4)]);
            app.axes_a_de_img.XLim = [0 I.XData(2)];
            app.axes_a_de_img.YLim = [0 I.YData(2)];
            app.axes_a_de_img.Title.String = '待解密图像';
            
            if zero_robust_flag == 1 && test_img_flag == 1 && zero_frag_flag == 1 && key_flag == 1
                app.btn_a_decrypt.Enable = 'on';
            end
        end

        % Button pushed function: btn_a_encrypt
        function btn_a_encrypt_Callback(app, event)
            app.Lamp_en_status.Color = [1.00, 0.50, 0.00];
            app.Label_en_status.Text = '加密中';
            app.loading_en_Image.Visible = 'on';
            
            global rb;
            global cr;
            global yt;
            I_original=yt;
            I_robust_seal=rb;
            I_fragile_seal=cr;
            
            sign = 0;
            %             加密
            %             $ TODO:
            % 接口
            switch app.DropDown_en_robust.Value
                case 'BP网络'
                    encrypt_function = 1;
                    [zero_robust_seal, net] = BP_robust_encrypt(I_original, I_robust_seal);
                case 'CNN'
                    encrypt_function = 2;
                    [zero_robust_seal, net] = CNN_robust_encrypt(I_original, I_robust_seal);
            end
            
            
            switch app.DropDown_en_frag.Value
                case 'SVD'
                    frag_function = 1;
                    zero_fragile_seal = SVD_frag_encrypt(I_original, I_fragile_seal);
                case '比特平面'
                    frag_function = 2;
                    zero_fragile_seal = Bit_frag_encrypt(I_original, I_fragile_seal);

            end
            
            
            I1 = imshow(zero_robust_seal, 'InitialMagnification', 'fit', 'Parent', app.axes_a_en_robust_show, ...
                'XData', [1 app.axes_a_en_robust_show.Position(3)], 'YData', [1 app.axes_a_en_robust_show.Position(4)]);
            app.axes_a_en_robust_show.XLim = [0 I1.XData(2)];
            app.axes_a_en_robust_show.YLim = [0 I1.YData(2)];
            app.axes_a_en_robust_show.Title.String = '加密鲁棒水印';
            
            I2 = imshow(zero_fragile_seal, 'InitialMagnification', 'fit', 'Parent', app.axes_a_en_frag_show, ...
                'XData', [1 app.axes_a_en_frag_show.Position(3)], 'YData', [1 app.axes_a_en_frag_show.Position(4)]);
            app.axes_a_en_frag_show.XLim = [0 I2.XData(2)];
            app.axes_a_en_frag_show.YLim = [0 I2.YData(2)];
            app.axes_a_en_frag_show.Title.String = '加密脆弱水印';
            
            % 保存相关文件
            save('encryption/encrypt_info.mat', 'encrypt_function', 'frag_function', 'net');
            imwrite(mat2gray(zero_fragile_seal), 'encryption/zero_fragile_seal.tif');
            imwrite(mat2gray(zero_robust_seal), 'encryption/zero_robust_seal.tif');
            
            app.Lamp_en_status.Color = [0.00, 1.00, 0.00];
            app.Label_en_status.Text = '完成';
            app.loading_en_Image.Visible = 'off';
        end

        % Button pushed function: btn_a_change_img
        function btn_a_change_img_Callback(app, event)
            global zero_robust_flag;
            global test_img_flag;
            global zero_frag_flag;
            global key_flag;
            global original_image;
            global om;
            global om_temp;
            
            [filename,pathname] = uigetfile({'*.*';'*.bmp';'*.jpg';'*.tif';'*.jpeg'},'选择图像');
            %如果没有输入路径，则弹出错误对话框
            if isequal(filename,0)||isequal(pathname,0)
                f = app.figure1;
                uialert(f, '您还没有选取图片', '注意', 'Icon', 'warning');
                return;
            else
                %合成路径+文件名
                image=[pathname,filename];
                %读取图像
                om = imread(image);
                om_temp = om;
                original_image = om;
                
                test_img_flag = 1;
                
                app.TabGroup_main.SelectedTab = app.TabGroup_main.Children(3);
                
                % 原图
                I1 = imshow(om, 'InitialMagnification', 'fit', 'Parent', app.axes_b_original_img, ...
                    'XData', [1 app.axes_b_original_img.Position(3)], 'YData', [1 app.axes_b_original_img.Position(4)]);
                app.axes_b_original_img.XLim = [0 I1.XData(2)];
                app.axes_b_original_img.YLim = [0 I1.YData(2)];
                app.axes_b_original_img.Title.String = '原图';
                
                % 预览
                I2 = imshow(om, 'InitialMagnification', 'fit', 'Parent', app.axes_b_preview_img, ...
                    'XData', [1 app.axes_b_preview_img.Position(3)], 'YData', [1 app.axes_b_preview_img.Position(4)]);
                app.axes_b_preview_img.XLim = [0 I2.XData(2)];
                app.axes_b_preview_img.YLim = [0 I2.YData(2)];
                app.axes_b_preview_img.Title.String = '预览';
                
                % 更改结果
                I3 = imshow(om, 'InitialMagnification', 'fit', 'Parent', app.axes_b_changed_img, ...
                    'XData', [1 app.axes_b_changed_img.Position(3)], 'YData', [1 app.axes_b_changed_img.Position(4)]);
                app.axes_b_changed_img.XLim = [0 I3.XData(2)];
                app.axes_b_changed_img.YLim = [0 I3.YData(2)];
                app.axes_b_changed_img.Title.String = '更改结果';
                
                % 待解密图像
                I4 = imshow(om, 'InitialMagnification', 'fit', 'Parent', app.axes_a_de_img, ...
                    'XData', [1 app.axes_a_de_img.Position(3)], 'YData', [1 app.axes_a_de_img.Position(4)]);
                app.axes_a_de_img.XLim = [0 I4.XData(2)];
                app.axes_a_de_img.YLim = [0 I4.YData(2)];
                app.axes_a_de_img.Title.String = '待解密图像';
            end
            
            if zero_robust_flag == 1 && test_img_flag == 1 && zero_frag_flag == 1 && key_flag == 1
                app.btn_a_decrypt.Enable = 'on';
            end
        end

        % Button pushed function: btn_crop
        function btn_crop_Callback(app, event)
            global om;
            global om_temp;
            
            roi = drawrectangle(app.axes_b_preview_img);
            
            om_temp = imcrop(om, roi.Position);
            
            % 预览
            I = imshow(om_temp, 'InitialMagnification', 'fit', 'Parent', app.axes_b_preview_img, ...
                'XData', [1 app.axes_b_preview_img.Position(3)], 'YData', [1 app.axes_b_preview_img.Position(4)]);
            app.axes_b_preview_img.XLim = [0 I.XData(2)];
            app.axes_b_preview_img.YLim = [0 I.YData(2)];
            app.axes_b_preview_img.Title.String = '预览';
            
        end

        % Button pushed function: btn_b_confirm_tmp
        function btn_b_confirm_tmp_Callback(app, event)
            global om;
            global om_temp;
            
            om = om_temp;
            
            % 更改结果
            I = imshow(om_temp, 'InitialMagnification', 'fit', 'Parent', app.axes_b_changed_img, ...
                'XData', [1 app.axes_b_changed_img.Position(3)], 'YData', [1 app.axes_b_changed_img.Position(4)]);
            app.axes_b_changed_img.XLim = [0 I.XData(2)];
            app.axes_b_changed_img.YLim = [0 I.YData(2)];
            app.axes_b_changed_img.Title.String = '更改结果';
            
        end

        % Value changing function: Knob_rotate_angle
        function Knob_rotate_angleValueChanging(app, event)
            global om;
            global om_temp
            changingValue = event.Value;
            om_temp = imrotate(om,changingValue,'bilinear','crop');
            app.EditField_rotate_angle.Value = changingValue;
            
            % 预览
            I = imshow(om_temp, 'InitialMagnification', 'fit', 'Parent', app.axes_b_preview_img, ...
                'XData', [1 app.axes_b_preview_img.Position(3)], 'YData', [1 app.axes_b_preview_img.Position(4)]);
            app.axes_b_preview_img.XLim = [0 I.XData(2)];
            app.axes_b_preview_img.YLim = [0 I.YData(2)];
            app.axes_b_preview_img.Title.String = '预览';
            
        end

        % Value changed function: EditField_rotate_angle
        function EditField_rotate_angleValueChanged(app, event)
            value = app.EditField_rotate_angle.Value;
            global om;
            global om_temp
            om_temp = imrotate(om,value,'bilinear','crop');
            app.Knob_rotate_angle.Value = value;
            
            % 预览
            I = imshow(om_temp, 'InitialMagnification', 'fit', 'Parent', app.axes_b_preview_img, ...
                'XData', [1 app.axes_b_preview_img.Position(3)], 'YData', [1 app.axes_b_preview_img.Position(4)]);
            app.axes_b_preview_img.XLim = [0 I.XData(2)];
            app.axes_b_preview_img.YLim = [0 I.YData(2)];
            app.axes_b_preview_img.Title.String = '预览';
        end

        % Value changing function: Slider_gaussian
        function Slider_gaussianValueChanging(app, event)
            global om;
            global om_temp
            changingValue = event.Value;
            om_temp = imnoise(om, 'gaussian', 0, changingValue);
            app.EditField_gaussian.Value = changingValue;
            
            % 预览
            I = imshow(om_temp, 'InitialMagnification', 'fit', 'Parent', app.axes_b_preview_img, ...
                'XData', [1 app.axes_b_preview_img.Position(3)], 'YData', [1 app.axes_b_preview_img.Position(4)]);
            app.axes_b_preview_img.XLim = [0 I.XData(2)];
            app.axes_b_preview_img.YLim = [0 I.YData(2)];
            app.axes_b_preview_img.Title.String = '预览';
        end

        % Value changed function: EditField_gaussian
        function EditField_gaussianValueChanged(app, event)
            value = app.EditField_gaussian.Value;
            global om;
            global om_temp
            om_temp = imnoise(om, 'gaussian', 0, value);
            app.Slider_gaussian.Value = value;
            
            % 预览
            I = imshow(om_temp, 'InitialMagnification', 'fit', 'Parent', app.axes_b_preview_img, ...
                'XData', [1 app.axes_b_preview_img.Position(3)], 'YData', [1 app.axes_b_preview_img.Position(4)]);
            app.axes_b_preview_img.XLim = [0 I.XData(2)];
            app.axes_b_preview_img.YLim = [0 I.YData(2)];
            app.axes_b_preview_img.Title.String = '预览';
        end

        % Button pushed function: btn_b_redo
        function btn_b_redoButtonPushed(app, event)
            global original_image;
            global om;
            global om_temp;
            
            om = original_image;
            om_temp = om;
            
            % 预览
            I1 = imshow(om, 'InitialMagnification', 'fit', 'Parent', app.axes_b_preview_img, ...
                'XData', [1 app.axes_b_preview_img.Position(3)], 'YData', [1 app.axes_b_preview_img.Position(4)]);
            app.axes_b_preview_img.XLim = [0 I1.XData(2)];
            app.axes_b_preview_img.YLim = [0 I1.YData(2)];
            app.axes_b_preview_img.Title.String = '预览';
            
            % 更改结果
            I2 = imshow(om, 'InitialMagnification', 'fit', 'Parent', app.axes_b_changed_img, ...
                'XData', [1 app.axes_b_changed_img.Position(3)], 'YData', [1 app.axes_b_changed_img.Position(4)]);
            app.axes_b_changed_img.XLim = [0 I2.XData(2)];
            app.axes_b_changed_img.YLim = [0 I2.YData(2)];
            app.axes_b_changed_img.Title.String = '更改结果';
            
            app.EditField_rotate_angle.Value = 0;
            app.Knob_rotate_angle.Value = 0;
            app.EditField_gaussian.Value = 0;
            app.Slider_gaussian.Value = 0;
            app.EditField_salt.Value = 0;
            app.Slider_salt.Value = 0;
            app.EditField_midfilt.Value = 1;
            app.Slider_midfilt.Value = 1;
            app.Knob_jpeg.Value = 100;
            app.EditField_jpeg.Value = 100;
        end

        % Value changing function: Slider_salt
        function Slider_saltValueChanging(app, event)
            global om;
            global om_temp
            changingValue = event.Value;
            om_temp = imnoise(om, 'salt & pepper', changingValue);
            app.EditField_salt.Value = changingValue;
            
            % 预览
            I = imshow(om_temp, 'InitialMagnification', 'fit', 'Parent', app.axes_b_preview_img, ...
                'XData', [1 app.axes_b_preview_img.Position(3)], 'YData', [1 app.axes_b_preview_img.Position(4)]);
            app.axes_b_preview_img.XLim = [0 I.XData(2)];
            app.axes_b_preview_img.YLim = [0 I.YData(2)];
            app.axes_b_preview_img.Title.String = '预览';
        end

        % Value changed function: EditField_salt
        function EditField_saltValueChanged(app, event)
            value = app.EditField_salt.Value;
            
            global om;
            global om_temp
            om_temp = imnoise(om, 'salt & pepper', value);
            app.Slider_salt.Value = value;
            
            % 预览
            I = imshow(om_temp, 'InitialMagnification', 'fit', 'Parent', app.axes_b_preview_img, ...
                'XData', [1 app.axes_b_preview_img.Position(3)], 'YData', [1 app.axes_b_preview_img.Position(4)]);
            app.axes_b_preview_img.XLim = [0 I.XData(2)];
            app.axes_b_preview_img.YLim = [0 I.YData(2)];
            app.axes_b_preview_img.Title.String = '预览';
        end

        % Value changing function: Slider_midfilt
        function Slider_midfiltValueChanging(app, event)
            global om;
            global om_temp;
            changingValue = round(event.Value);
            app.EditField_midfilt.Value = changingValue;
            for i = 1:3
                om_temp(:,:,i) = medfilt2(om(:,:,i), [changingValue, changingValue]);
            end
            
            % 预览
            I = imshow(om_temp, 'InitialMagnification', 'fit', 'Parent', app.axes_b_preview_img, ...
                'XData', [1 app.axes_b_preview_img.Position(3)], 'YData', [1 app.axes_b_preview_img.Position(4)]);
            app.axes_b_preview_img.XLim = [0 I.XData(2)];
            app.axes_b_preview_img.YLim = [0 I.YData(2)];
            app.axes_b_preview_img.Title.String = '预览';
        end

        % Value changed function: EditField_midfilt
        function EditField_midfiltValueChanged(app, event)
            global om;
            global om_temp;
            value = app.EditField_midfilt.Value;
            app.Slider_midfilt.Value = value;
            for i = 1:3
                om_temp(:,:,i) = medfilt2(om(:,:,i), [value, value]);
            end
            
            % 预览
            I = imshow(om_temp, 'InitialMagnification', 'fit', 'Parent', app.axes_b_preview_img, ...
                'XData', [1 app.axes_b_preview_img.Position(3)], 'YData', [1 app.axes_b_preview_img.Position(4)]);
            app.axes_b_preview_img.XLim = [0 I.XData(2)];
            app.axes_b_preview_img.YLim = [0 I.YData(2)];
            app.axes_b_preview_img.Title.String = '预览';
        end

        % Value changing function: Knob_jpeg
        function Knob_jpegValueChanging(app, event)
            global om;
            global om_temp;
            changingValue = round(event.Value);
            app.EditField_jpeg.Value = changingValue;
            
            imwrite(om, ['img/tmp', '.jpg'], 'Quality', changingValue);
            om_temp = imread(['img/tmp', '.jpg']);
            
            % 预览
            I = imshow(om_temp, 'InitialMagnification', 'fit', 'Parent', app.axes_b_preview_img, ...
                'XData', [1 app.axes_b_preview_img.Position(3)], 'YData', [1 app.axes_b_preview_img.Position(4)]);
            app.axes_b_preview_img.XLim = [0 I.XData(2)];
            app.axes_b_preview_img.YLim = [0 I.YData(2)];
            app.axes_b_preview_img.Title.String = '预览';
        end

        % Value changed function: EditField_jpeg
        function EditField_jpegValueChanged(app, event)
            global om;
            global om_temp;
            value = app.EditField_jpeg.Value;
            app.Knob_jpeg.Value = value;
            
            imwrite(om, ['img/tmp', '.jpg'], 'Quality', value);
            om_temp = imread(['img/tmp', '.jpg']);
            
            % 预览
            I = imshow(om_temp, 'InitialMagnification', 'fit', 'Parent', app.axes_b_preview_img, ...
                'XData', [1 app.axes_b_preview_img.Position(3)], 'YData', [1 app.axes_b_preview_img.Position(4)]);
            app.axes_b_preview_img.XLim = [0 I.XData(2)];
            app.axes_b_preview_img.YLim = [0 I.YData(2)];
            app.axes_b_preview_img.Title.String = '预览';
        end

        % Button pushed function: btn_a_zero_robust
        function btn_a_zero_robustButtonPushed(app, event)
            global zero_robust_flag;
            global test_img_flag;
            global zero_frag_flag;
            global key_flag;
            
            global zero_robust;
            
            %选择图片，可以是以下格式（*这个符号是通配符，表示可以是任意长度的任意字符）
            [filename,pathname] = uigetfile({'*.*';'*.bmp';'*.jpg';'*.tif';'*.jpeg'},'选择图像');
            
            if isequal(filename,0)||isequal(pathname,0)
                f = app.figure1;
                uialert(f, '您还没有选取图片', '注意', 'Icon', 'warning');
                %                 errordlg('您还没有选取图片！！','程序员哥哥告诉你');
                return;
            else
                %合成路径+文件名
                image=[pathname,filename];
                %读取图像
                zero_robust=imread(image);
                if size(zero_robust,3) == 3
                    zero_robust = rgb2gray(zero_robust);
                end
                zero_robust = logical(zero_robust);
                
                
                I = imshow(zero_robust, 'InitialMagnification', 'fit', 'Parent', app.axes_a_de_robust, ...
                    'XData', [1 app.axes_a_de_robust.Position(3)], 'YData', [1 app.axes_a_de_robust.Position(4)]);
                app.axes_a_de_robust.XLim = [0 I.XData(2)];
                app.axes_a_de_robust.YLim = [0 I.YData(2)];
                app.axes_a_de_robust.Title.String = '零鲁棒水印';
                
                zero_robust_flag = 1;
            end
            
            if zero_robust_flag == 1 && test_img_flag == 1 && zero_frag_flag == 1 && key_flag == 1
                app.btn_a_decrypt.Enable = 'on';
            end
        end

        % Button pushed function: btn_a_zero_frag
        function btn_a_zero_fragButtonPushed(app, event)
            global zero_robust_flag;
            global test_img_flag;
            global zero_frag_flag;
            global key_flag;
            
            global zero_frag;
            
            %选择图片，可以是以下格式（*这个符号是通配符，表示可以是任意长度的任意字符）
            [filename,pathname] = uigetfile({'*.*';'*.bmp';'*.jpg';'*.tif';'*.jpeg'},'选择图像');
            
            if isequal(filename,0)||isequal(pathname,0)
                f = app.figure1;
                uialert(f, '您还没有选取图片', '注意', 'Icon', 'warning');
                %                 errordlg('您还没有选取图片！！','程序员哥哥告诉你');
                return;
            else
                %合成路径+文件名
                image=[pathname,filename];
                %读取图像
                zero_frag=imread(image);
                if size(zero_frag,3) == 3
                    zero_frag = rgb2gray(zero_frag);
                end
                zero_frag = logical(zero_frag);
                
                I = imshow(zero_frag, 'InitialMagnification', 'fit', 'Parent', app.axes_a_de_frag, ...
                    'XData', [1 app.axes_a_de_frag.Position(3)], 'YData', [1 app.axes_a_de_frag.Position(4)]);
                app.axes_a_de_frag.XLim = [0 I.XData(2)];
                app.axes_a_de_frag.YLim = [0 I.YData(2)];
                app.axes_a_de_frag.Title.String = '零脆弱水印';
                
                zero_frag_flag = 1;
            end
            
            if zero_robust_flag == 1 && test_img_flag == 1 && zero_frag_flag == 1 && key_flag == 1
                app.btn_a_decrypt.Enable = 'on';
            end
        end

        % Button pushed function: btn_a_key
        function btn_a_keyButtonPushed(app, event)
            global zero_robust_flag;
            global test_img_flag;
            global zero_frag_flag;
            global key_flag;
            
            global key;
            
            [filename,pathname] = uigetfile({'*.mat'},'选择密钥');
            
            if isequal(filename,0)||isequal(pathname,0)
                f = app.figure1;
                uialert(f, '您还没有选取密钥', '注意', 'Icon', 'warning');
                %                 errordlg('您还没有选取密钥！！','程序员哥哥告诉你');
                return;
            else
                key = load([pathname,filename]);
                app.key_path_Label.Text = [pathname,filename];
                key_flag = 1;
            end
            
            if zero_robust_flag == 1 && test_img_flag == 1 && zero_frag_flag == 1 && key_flag == 1
                app.btn_a_decrypt.Enable = 'on';
            end
        end

        % Button pushed function: btn_s_robust_original
        function btn_s_robust_originalButtonPushed(app, event)
            global robust_original;
            global robust_original_flag;
            global robust_decrypt;
            global robust_decrypt_flag;
            
            [filename,pathname] = uigetfile({'*.*';'*.bmp';'*.jpg';'*.tif';'*.jpeg'},'选择图像');
            
            %如果没有输入路径，则弹出错误对话框
            if isequal(filename,0)||isequal(pathname,0)
                f = app.figure1;
                uialert(f, '您还没有选取图片', '注意', 'Icon', 'warning');
                return;
            else
                %合成路径+文件名
                image=[pathname,filename];
                %读取图像
                robust_original = imread(image);
                if size(robust_original,3) == 3
                    robust_original = rgb2gray(robust_original);
                end
                robust_original = logical(robust_original);
                
                I = imshow(robust_original, 'InitialMagnification', 'fit', 'Parent', app.axes_s_robust_original, ...
                    'XData', [1 app.axes_s_robust_original.Position(3)], 'YData', [1 app.axes_s_robust_original.Position(4)]);
                app.axes_s_robust_original.XLim = [0 I.XData(2)];
                app.axes_s_robust_original.YLim = [0 I.YData(2)];
                app.axes_s_robust_original.Title.String = '原始鲁棒水印';
                
                robust_original_flag = 1;
            end
            
            if robust_decrypt_flag == 1 && robust_original_flag == 1
                app.EditField_nc_robust.Value = nc(robust_original, robust_decrypt);
                app.EditField_taf_robust.Value = TAF(robust_original, robust_decrypt);
            end
        end

        % Button pushed function: btn_s_frag_original
        function btn_s_frag_originalButtonPushed(app, event)
            global frag_original;
            global frag_original_flag;
            global frag_decrypt;
            global frag_decrypt_flag;
            
            [filename,pathname] = uigetfile({'*.*';'*.bmp';'*.jpg';'*.tif';'*.jpeg'},'选择图像');
            
            %如果没有输入路径，则弹出错误对话框
            if isequal(filename,0)||isequal(pathname,0)
                f = app.figure1;
                uialert(f, '您还没有选取图片', '注意', 'Icon', 'warning');
                return;
            else
                %合成路径+文件名
                image=[pathname,filename];
                %读取图像
                frag_original = imread(image);
                if size(frag_original,3) == 3
                    frag_original = rgb2gray(frag_original);
                end
                frag_original = logical(frag_original);
                
                I = imshow(frag_original, 'InitialMagnification', 'fit', 'Parent', app.axes_s_frag_original, ...
                    'XData', [1 app.axes_s_frag_original.Position(3)], 'YData', [1 app.axes_s_frag_original.Position(4)]);
                app.axes_s_frag_original.XLim = [0 I.XData(2)];
                app.axes_s_frag_original.YLim = [0 I.YData(2)];
                app.axes_s_frag_original.Title.String = '原始脆弱水印';
                
                frag_original_flag = 1;
            end
            
            if frag_decrypt_flag == 1 && frag_original_flag == 1
                app.EditField_nc_frag.Value = nc(frag_original, frag_decrypt);
                app.EditField_taf_frag.Value = TAF(frag_original, frag_decrypt);
            end
        end

        % Button pushed function: btn_s_robust_decrypt
        function btn_s_robust_decryptButtonPushed(app, event)
            global robust_original;
            global robust_original_flag;
            global robust_decrypt;
            global robust_decrypt_flag;
            
            [filename,pathname] = uigetfile({'*.*';'*.bmp';'*.jpg';'*.tif';'*.jpeg'},'选择图像');
            
            %如果没有输入路径，则弹出错误对话框
            if isequal(filename,0)||isequal(pathname,0)
                f = app.figure1;
                uialert(f, '您还没有选取图片', '注意', 'Icon', 'warning');
                return;
            else
                %合成路径+文件名
                image=[pathname,filename];
                %读取图像
                robust_decrypt = imread(image);
                if size(robust_decrypt,3) == 3
                    robust_decrypt = rgb2gray(robust_decrypt);
                end
                robust_decrypt = logical(robust_decrypt);
                
                I = imshow(robust_decrypt, 'InitialMagnification', 'fit', 'Parent', app.axes_s_robust_decrypt, ...
                    'XData', [1 app.axes_s_robust_decrypt.Position(3)], 'YData', [1 app.axes_s_robust_decrypt.Position(4)]);
                app.axes_s_robust_decrypt.XLim = [0 I.XData(2)];
                app.axes_s_robust_decrypt.YLim = [0 I.YData(2)];
                app.axes_s_robust_decrypt.Title.String = '复原鲁棒水印';
                
                robust_decrypt_flag = 1;
            end
            
            if robust_decrypt_flag == 1 && robust_original_flag == 1
                app.EditField_nc_robust.Value = nc(robust_original, robust_decrypt);
                app.EditField_taf_robust.Value = TAF(robust_original, robust_decrypt);
            end
        end

        % Button pushed function: btn_s_frag_decrypt
        function btn_s_frag_decryptButtonPushed(app, event)
            global frag_original;
            global frag_original_flag;
            global frag_decrypt;
            global frag_decrypt_flag;
            
            [filename,pathname] = uigetfile({'*.*';'*.bmp';'*.jpg';'*.tif';'*.jpeg'},'选择图像');
            
            %如果没有输入路径，则弹出错误对话框
            if isequal(filename,0)||isequal(pathname,0)
                f = app.figure1;
                uialert(f, '您还没有选取图片', '注意', 'Icon', 'warning');
                return;
            else
                %合成路径+文件名
                image=[pathname,filename];
                %读取图像
                frag_decrypt = imread(image);
                if size(frag_decrypt,3) == 3
                    frag_decrypt = rgb2gray(frag_decrypt);
                end
                frag_decrypt = logical(frag_decrypt);
                
                I = imshow(frag_decrypt, 'InitialMagnification', 'fit', 'Parent', app.axes_s_frag_decrypt, ...
                    'XData', [1 app.axes_s_frag_decrypt.Position(3)], 'YData', [1 app.axes_s_frag_decrypt.Position(4)]);
                app.axes_s_frag_decrypt.XLim = [0 I.XData(2)];
                app.axes_s_frag_decrypt.YLim = [0 I.YData(2)];
                app.axes_s_frag_decrypt.Title.String = '复原脆弱水印';
                
                frag_decrypt_flag = 1;
            end
            
            if frag_decrypt_flag == 1 && frag_original_flag == 1
                app.EditField_nc_frag.Value = nc(frag_original, frag_decrypt);
                app.EditField_taf_frag.Value = TAF(frag_original, frag_decrypt);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = '图像零水印加密验证系统';
            app.TabGroup_main = uitabgroup(app.figure1);
            app.Tab_encrypted = uitab(app.TabGroup_main);
            app.Tab_encrypted.Title = '加密';
            app.btn_a_encrypt = uibutton(app.Tab_encrypted, 'push');
            app.btn_a_encrypt.ButtonPushedFcn = createCallbackFcn(app, @btn_a_encrypt_Callback, true);
            app.btn_a_encrypt.Enable = 'off';
            app.btn_a_encrypt.Text = '开始加密';
            app.Lamp_en_status = uilamp(app.Tab_encrypted);
            app.Label_en_status = uilabel(app.Tab_encrypted);
            app.Label_en_status.Text = '状态';
            app.Panel_en = uipanel(app.Tab_encrypted);
            app.Panel_en.Title = '加密';
            app.btn_a_input_img = uibutton(app.Panel_en, 'push');
            app.btn_a_input_img.ButtonPushedFcn = createCallbackFcn(app, @btn_a_input_img_Callback, true);
            app.btn_a_input_img.Text = '导入原图';
            app.btn_a_input_robust = uibutton(app.Panel_en, 'push');
            app.btn_a_input_robust.ButtonPushedFcn = createCallbackFcn(app, @btn_a_input_robust_Callback, true);
            app.btn_a_input_robust.Text = '导入鲁棒水印';
            app.btn_a_input_frag = uibutton(app.Panel_en, 'push');
            app.btn_a_input_frag.ButtonPushedFcn = createCallbackFcn(app, @btn_a_input_frag_Callback, true);
            app.btn_a_input_frag.Text = '导入脆弱水印';
            app.axes_a_original_img = uiaxes(app.Panel_en);
            title(app.axes_a_original_img, '原图')
            app.axes_a_original_img.XTick = [];
            app.axes_a_original_img.YTick = [];
            app.axes_a_en_robust = uiaxes(app.Panel_en);
            title(app.axes_a_en_robust, '鲁棒水印')
            app.axes_a_en_robust.XTick = [];
            app.axes_a_en_robust.YTick = [];
            app.axes_a_en_frag = uiaxes(app.Panel_en);
            title(app.axes_a_en_frag, '脆弱水印')
            app.axes_a_en_frag.XTick = [];
            app.axes_a_en_frag.YTick = [];
            app.Panel_show_a = uipanel(app.Tab_encrypted);
            app.Panel_show_a.Title = '加密结果预览';
            app.axes_a_en_robust_show = uiaxes(app.Panel_show_a);
            title(app.axes_a_en_robust_show, '加密鲁棒水印')
            app.axes_a_en_robust_show.XTick = [];
            app.axes_a_en_robust_show.YTick = [];
            app.axes_a_en_frag_show = uiaxes(app.Panel_show_a);
            title(app.axes_a_en_frag_show, '加密脆弱水印')
            app.axes_a_en_frag_show.XTick = [];
            app.axes_a_en_frag_show.YTick = [];
            app.Label = uilabel(app.Tab_encrypted);
            app.Label.Text = '鲁棒加密算法';
            app.DropDown_en_robust = uidropdown(app.Tab_encrypted);
            app.DropDown_en_robust.Items = {'BP网络', 'CNN'};
            app.DropDown_en_robust.Value = 'BP网络';
            app.Label_2 = uilabel(app.Tab_encrypted);
            app.Label_2.Text = '脆弱加密算法';
            app.DropDown_en_frag = uidropdown(app.Tab_encrypted);
            app.DropDown_en_frag.Items = {'SVD', '比特平面'};
            app.DropDown_en_frag.Value = 'SVD';
            app.loading_en_Image = uiimage(app.Tab_encrypted);
            app.loading_en_Image.Visible = 'off';
            app.loading_en_Image.ImageSource = 'loading.gif';
            app.Tab_decrypt = uitab(app.TabGroup_main);
            app.Tab_decrypt.Title = '解密';
            app.Panel_de = uipanel(app.Tab_decrypt);
            app.Panel_de.Title = '解密';
            app.btn_a_change_img = uibutton(app.Panel_de, 'push');
            app.btn_a_change_img.ButtonPushedFcn = createCallbackFcn(app, @btn_a_change_img_Callback, true);
            app.btn_a_change_img.Text = '导入待解密图像';
            app.axes_a_de_img = uiaxes(app.Panel_de);
            title(app.axes_a_de_img, '待解密图像')
            app.axes_a_de_img.XTick = [];
            app.axes_a_de_img.YTick = [];
            app.btn_a_zero_robust = uibutton(app.Panel_de, 'push');
            app.btn_a_zero_robust.ButtonPushedFcn = createCallbackFcn(app, @btn_a_zero_robustButtonPushed, true);
            app.btn_a_zero_robust.Text = '导入零鲁棒水印';
            app.btn_a_zero_frag = uibutton(app.Panel_de, 'push');
            app.btn_a_zero_frag.ButtonPushedFcn = createCallbackFcn(app, @btn_a_zero_fragButtonPushed, true);
            app.btn_a_zero_frag.Text = '导入零脆弱水印';
            app.btn_a_key = uibutton(app.Panel_de, 'push');
            app.btn_a_key.ButtonPushedFcn = createCallbackFcn(app, @btn_a_keyButtonPushed, true);
            app.btn_a_key.Text = '导入密钥';
            app.key_path_Label = uilabel(app.Panel_de);
            app.key_path_Label.Text = '未导入';
            app.text_Label = uilabel(app.Panel_de);
            app.text_Label.Text = '密钥文件：';
            app.axes_a_de_robust = uiaxes(app.Panel_de);
            title(app.axes_a_de_robust, '零鲁棒水印')
            app.axes_a_de_robust.XTick = [];
            app.axes_a_de_robust.YTick = [];
            app.axes_a_de_frag = uiaxes(app.Panel_de);
            title(app.axes_a_de_frag, '零脆弱水印')
            app.axes_a_de_frag.XTick = [];
            app.axes_a_de_frag.YTick = [];
            app.btn_a_decrypt = uibutton(app.Tab_decrypt, 'push');
            app.btn_a_decrypt.ButtonPushedFcn = createCallbackFcn(app, @btn_a_decrypt_Callback, true);
            app.btn_a_decrypt.Enable = 'off';
            app.btn_a_decrypt.Text = '开始解密';
            app.Panel_show_c = uipanel(app.Tab_decrypt);
            app.Panel_show_c.Title = '解密结果预览';
            app.axes_a_de_robust_show = uiaxes(app.Panel_show_c);
            title(app.axes_a_de_robust_show, '解密鲁棒水印')
            app.axes_a_de_robust_show.XTick = [];
            app.axes_a_de_robust_show.YTick = [];
            app.axes_a_de_frag_show = uiaxes(app.Panel_show_c);
            title(app.axes_a_de_frag_show, '解密脆弱水印')
            app.axes_a_de_frag_show.XLim = [0 1];
            app.axes_a_de_frag_show.XTick = [];
            app.axes_a_de_frag_show.YTick = [];
            app.Lamp_de_status = uilamp(app.Tab_decrypt);
            app.Label_de_status = uilabel(app.Tab_decrypt);
            app.Label_de_status.Text = '状态';
            app.Label_3 = uilabel(app.Tab_decrypt);
            app.Label_3.Text = '鲁棒解密算法';
            app.DropDown_de_robust = uidropdown(app.Tab_decrypt);
            app.DropDown_de_robust.Items = {'BP网络', 'CNN'};
            app.DropDown_de_robust.Value = 'BP网络';
            app.Label_4 = uilabel(app.Tab_decrypt);
            app.Label_4.Text = '脆弱解密算法';
            app.DropDown_de_frag = uidropdown(app.Tab_decrypt);
            app.DropDown_de_frag.Items = {'SVD', '比特平面'};
            app.DropDown_de_frag.Value = 'SVD';
            app.loading_de_Image = uiimage(app.Tab_decrypt);
            app.loading_de_Image.Visible = 'off';
            app.loading_de_Image.ImageSource = 'loading.gif';
            app.Tab_editing = uitab(app.TabGroup_main);
            app.Tab_editing.Title = '图像修改';
            app.btn_b_confirm_change = uibutton(app.Tab_editing, 'push');
            app.btn_b_confirm_change.ButtonPushedFcn = createCallbackFcn(app, @btn_b_confirm_change_Callback, true);
            app.btn_b_confirm_change.Text = '确认修改';
            app.axes_b_original_img = uiaxes(app.Tab_editing);
            title(app.axes_b_original_img, '原图')
            app.axes_b_original_img.XTick = [];
            app.axes_b_original_img.YTick = [];
            app.axes_b_preview_img = uiaxes(app.Tab_editing);
            title(app.axes_b_preview_img, '预览')
            app.axes_b_preview_img.XTick = [];
            app.axes_b_preview_img.YTick = [];
            app.TabGroup_b_subgroup = uitabgroup(app.Tab_editing);
            app.Tab_rotate = uitab(app.TabGroup_b_subgroup);
            app.Tab_rotate.Title = '旋转';
            app.Knob_rotate_angle = uiknob(app.Tab_rotate, 'continuous');
            app.Knob_rotate_angle.Limits = [0 360];
            app.Knob_rotate_angle.ValueChangingFcn = createCallbackFcn(app, @Knob_rotate_angleValueChanging, true);
            app.EditField_rotate_angle = uieditfield(app.Tab_rotate, 'numeric');
            app.EditField_rotate_angle.Limits = [0 360];
            app.EditField_rotate_angle.ValueDisplayFormat = '%.1f';
            app.EditField_rotate_angle.ValueChangedFcn = createCallbackFcn(app, @EditField_rotate_angleValueChanged, true);
            app.Tab_gaussian = uitab(app.TabGroup_b_subgroup);
            app.Tab_gaussian.Title = '高斯噪声';
            app.Slider_gaussian = uislider(app.Tab_gaussian);
            app.Slider_gaussian.Limits = [0 1];
            app.Slider_gaussian.ValueChangingFcn = createCallbackFcn(app, @Slider_gaussianValueChanging, true);
            app.EditField_gaussian = uieditfield(app.Tab_gaussian, 'numeric');
            app.EditField_gaussian.Limits = [0 1];
            app.EditField_gaussian.ValueDisplayFormat = '%.2f';
            app.EditField_gaussian.ValueChangedFcn = createCallbackFcn(app, @EditField_gaussianValueChanged, true);
            app.Tab_salt = uitab(app.TabGroup_b_subgroup);
            app.Tab_salt.Title = '椒盐噪声';
            app.Slider_salt = uislider(app.Tab_salt);
            app.Slider_salt.Limits = [0 1];
            app.Slider_salt.ValueChangingFcn = createCallbackFcn(app, @Slider_saltValueChanging, true);
            app.EditField_salt = uieditfield(app.Tab_salt, 'numeric');
            app.EditField_salt.Limits = [0 1];
            app.EditField_salt.ValueDisplayFormat = '%.2f';
            app.EditField_salt.ValueChangedFcn = createCallbackFcn(app, @EditField_saltValueChanged, true);
            app.Tab_crop = uitab(app.TabGroup_b_subgroup);
            app.Tab_crop.Title = '剪裁';
            app.btn_crop = uibutton(app.Tab_crop, 'push');
            app.btn_crop.ButtonPushedFcn = createCallbackFcn(app, @btn_crop_Callback, true);
            app.btn_crop.Text = '开始剪裁';
            app.Tab_jpeg = uitab(app.TabGroup_b_subgroup);
            app.Tab_jpeg.Title = 'jpeg压缩';
            app.Knob_jpeg = uiknob(app.Tab_jpeg, 'continuous');
            app.Knob_jpeg.ValueChangingFcn = createCallbackFcn(app, @Knob_jpegValueChanging, true);
            app.Knob_jpeg.Value = 100;
            app.EditField_jpeg = uieditfield(app.Tab_jpeg, 'numeric');
            app.EditField_jpeg.Limits = [0 100];
            app.EditField_jpeg.ValueDisplayFormat = '%.0f';
            app.EditField_jpeg.ValueChangedFcn = createCallbackFcn(app, @EditField_jpegValueChanged, true);
            app.EditField_jpeg.Value = 100;
            app.Tab_medfilt = uitab(app.TabGroup_b_subgroup);
            app.Tab_medfilt.Title = '中值滤波';
            app.Slider_midfilt = uislider(app.Tab_medfilt);
            app.Slider_midfilt.Limits = [1 100];
            app.Slider_midfilt.MajorTicks = [1 25 50 75 100];
            app.Slider_midfilt.MajorTickLabels = {'1', '25', '50', '75', '100'};
            app.Slider_midfilt.ValueChangingFcn = createCallbackFcn(app, @Slider_midfiltValueChanging, true);
            app.Slider_midfilt.Value = 1;
            app.EditField_midfilt = uieditfield(app.Tab_medfilt, 'numeric');
            app.EditField_midfilt.Limits = [1 100];
            app.EditField_midfilt.RoundFractionalValues = 'on';
            app.EditField_midfilt.ValueDisplayFormat = '%.0f';
            app.EditField_midfilt.ValueChangedFcn = createCallbackFcn(app, @EditField_midfiltValueChanged, true);
            app.EditField_midfilt.Value = 1;
            app.axes_b_changed_img = uiaxes(app.Tab_editing);
            title(app.axes_b_changed_img, '更改结果')
            app.axes_b_changed_img.XTick = [];
            app.axes_b_changed_img.YTick = [];
            app.btn_b_confirm_tmp = uibutton(app.Tab_editing, 'push');
            app.btn_b_confirm_tmp.ButtonPushedFcn = createCallbackFcn(app, @btn_b_confirm_tmp_Callback, true);
            app.btn_b_confirm_tmp.Text = '应用';
            app.btn_b_redo = uibutton(app.Tab_editing, 'push');
            app.btn_b_redo.ButtonPushedFcn = createCallbackFcn(app, @btn_b_redoButtonPushed, true);
            app.btn_b_redo.Text = '重置';
            app.Tab_similarity = uitab(app.TabGroup_main);
            app.Tab_similarity.Title = '相似度计算';
            app.axes_s_robust_original = uiaxes(app.Tab_similarity);
            title(app.axes_s_robust_original, '原始鲁棒水印')
            app.axes_s_robust_original.XTick = [];
            app.axes_s_robust_original.YTick = [];
            app.axes_s_frag_original = uiaxes(app.Tab_similarity);
            title(app.axes_s_frag_original, '原始脆弱水印')
            app.axes_s_frag_original.XTick = [];
            app.axes_s_frag_original.YTick = [];
            app.axes_s_robust_decrypt = uiaxes(app.Tab_similarity);
            title(app.axes_s_robust_decrypt, '复原鲁棒水印')
            app.axes_s_robust_decrypt.XTick = [];
            app.axes_s_robust_decrypt.YTick = [];
            app.axes_s_frag_decrypt = uiaxes(app.Tab_similarity);
            title(app.axes_s_frag_decrypt, '复原脆弱水印')
            app.axes_s_frag_decrypt.XTick = [];
            app.axes_s_frag_decrypt.YTick = [];
            app.btn_s_robust_original = uibutton(app.Tab_similarity, 'push');
            app.btn_s_robust_original.ButtonPushedFcn = createCallbackFcn(app, @btn_s_robust_originalButtonPushed, true);
            app.btn_s_robust_original.Text = '导入原始鲁棒水印';
            app.btn_s_frag_original = uibutton(app.Tab_similarity, 'push');
            app.btn_s_frag_original.ButtonPushedFcn = createCallbackFcn(app, @btn_s_frag_originalButtonPushed, true);
            app.btn_s_frag_original.Text = '导入原始脆弱水印';
            app.btn_s_robust_decrypt = uibutton(app.Tab_similarity, 'push');
            app.btn_s_robust_decrypt.ButtonPushedFcn = createCallbackFcn(app, @btn_s_robust_decryptButtonPushed, true);
            app.btn_s_robust_decrypt.Text = '导入复原鲁棒水印';
            app.btn_s_frag_decrypt = uibutton(app.Tab_similarity, 'push');
            app.btn_s_frag_decrypt.ButtonPushedFcn = createCallbackFcn(app, @btn_s_frag_decryptButtonPushed, true);
            app.btn_s_frag_decrypt.Text = '导入复原脆弱水印';
            app.NCLabel = uilabel(app.Tab_similarity);
            app.NCLabel.Text = 'NC';
            app.EditField_nc_robust = uieditfield(app.Tab_similarity, 'numeric');
            app.EditField_nc_robust.ValueDisplayFormat = '%.4f';
            app.EditField_nc_robust.Editable = 'off';
            app.EditField_nc_robust.Tooltip = {'归一化相关系数（Normalized Correlation），NC值越接近1，两幅图越相似'};
            app.TAFLabel = uilabel(app.Tab_similarity);
            app.TAFLabel.Text = 'TAF';
            app.EditField_taf_robust = uieditfield(app.Tab_similarity, 'numeric');
            app.EditField_taf_robust.ValueDisplayFormat = '%.4f';
            app.EditField_taf_robust.Editable = 'off';
            app.EditField_taf_robust.Tooltip = {'篡改评估函数（Tamper Assesment function），TAF值越接近0，两幅图越相似'};
            app.NCLabel_2 = uilabel(app.Tab_similarity);
            app.NCLabel_2.Text = 'NC';
            app.EditField_nc_frag = uieditfield(app.Tab_similarity, 'numeric');
            app.EditField_nc_frag.ValueDisplayFormat = '%.4f';
            app.EditField_nc_frag.Editable = 'off';
            app.EditField_nc_frag.Tooltip = {'归一化相关系数（Normalized Correlation），NC值越接近1，两幅图越相似'};
            app.TAFLabel_2 = uilabel(app.Tab_similarity);
            app.TAFLabel_2.Text = 'TAF';
            app.EditField_taf_frag = uieditfield(app.Tab_similarity, 'numeric');
            app.EditField_taf_frag.ValueDisplayFormat = '%.4f';
            app.EditField_taf_frag.Editable = 'off';
            app.EditField_taf_frag.Tooltip = {'篡改评估函数（Tamper Assesment function），TAF值越接近0，两幅图越相似'};
            app.PoweredbyLabel = uilabel(app.figure1);
            app.PoweredbyLabel.Text = 'Powered by 潘昱辰';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end